classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PlotPanel                     matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        UIAxes                        matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        AdaptiveSlicingTab            matlab.ui.container.Tab
        SettingsPanel                 matlab.ui.container.Panel
        STLfileDropDownLabel          matlab.ui.control.Label
        STLfileDropDown               matlab.ui.control.DropDown
        IncrementsEditFieldLabel      matlab.ui.control.Label
        IncrementsEditField           matlab.ui.control.NumericEditField
        CustomFileButton              matlab.ui.control.Button
        AdaptiveSlicingPanel          matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        SliceTypePanel                matlab.ui.container.Panel
        SimpleSlicingButton           matlab.ui.control.Button
        AdaptiveSlicingButton         matlab.ui.control.Button
        ViewTypeButtonGroup           matlab.ui.container.ButtonGroup
        ModelButton                   matlab.ui.control.RadioButton
        SlicingButton                 matlab.ui.control.RadioButton
        StructureButton               matlab.ui.control.RadioButton
        LayerViewerPanel              matlab.ui.container.Panel
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        CurvedLayerSlicingTab         matlab.ui.container.Tab
        SettingsPanel_2               matlab.ui.container.Panel
        STLfileDropDown_2Label        matlab.ui.control.Label
        STLfileDropDown_2             matlab.ui.control.DropDown
        CustomFileButton_2            matlab.ui.control.Button
        LoadModelButton               matlab.ui.control.Button
        FreeFormSurfacePanel          matlab.ui.container.Panel
        NoofXEditFieldLabel           matlab.ui.control.Label
        NoofXEditField                matlab.ui.control.NumericEditField
        CreatefreeformButton          matlab.ui.control.Button
        NoofYEditFieldLabel           matlab.ui.control.Label
        NoofYEditField                matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel      matlab.ui.control.Label
        ResolutionEditField           matlab.ui.control.NumericEditField
        LayerThicknessEditFieldLabel  matlab.ui.control.Label
        LayerThicknessEditField       matlab.ui.control.NumericEditField
        CurvedLayerSlicingPanel       matlab.ui.container.Panel
        NoofXEditField_2Label         matlab.ui.control.Label
        NoofXEditField_2              matlab.ui.control.NumericEditField
        CurvedLayerSliceButton        matlab.ui.control.Button
        NoofYEditField_2Label         matlab.ui.control.Label
        NoofYEditField_2              matlab.ui.control.NumericEditField
        PointsperpathEditFieldLabel   matlab.ui.control.Label
        PointsperpathEditField        matlab.ui.control.NumericEditField
        NoPathsEditFieldLabel         matlab.ui.control.Label
        NoPathsEditField              matlab.ui.control.NumericEditField
        ViewModeButtonGroup           matlab.ui.container.ButtonGroup
        ModelButton_2                 matlab.ui.control.RadioButton
        OneLayerButton                matlab.ui.control.RadioButton
        MultiLayerButton              matlab.ui.control.RadioButton
        LayerSettingsPanel            matlab.ui.container.Panel
        NumberofLayersLabel           matlab.ui.control.Label
        NumberofLayersSlider          matlab.ui.control.Slider
    end

    
    properties (Access = public)
        Property

    % General setting variables
    file = "Cone.stl"
    model; % Adaptive slicing model

    
    % Adaptive Slice variables
    increments = 1; % Decrease slices by factor
    thicknessArray = {0,0.1,0.2,0.5,1.0};
    angleArray = {0, 65, 79, 85, 90};
    residueArray = {0, 0.2, 0.5, 1, Inf};
    
    % Freeform surface variables
    numberX = 10;
    numberY = 10;
    resolution = 30;
    numberLayers = 1;
    layerThickness = 0.1;
    model2; % Freeform surface model
    model3; % Curved layer model
    % Curved Layer Slice variables
    numberX2 = 5;
    numberY2 = 5;
    numberPoints = 10;
    numberPaths = 30;
    layerNumber;
    
    % figure variables
    Xmin;
    Ymin;
    Zmin;
    Xmax;
    Ymax;
    Zmax;
    end
    
    methods (Access = private)
        
        function modelView(app) % Prints STL model
            cla(app.UIAxes);
            data = stlread("Models/" + app.file);
            dataSize =size(data.faces);
            n_elements = dataSize(1);
            n_nodes = n_elements * 3;
            ncon = data.faces;
            X= data.vertices(:,1);
            Y = data.vertices(:,2);
            Z = data.vertices(:,3);
            
            
            Xmin = Inf;
            Ymin = Inf;
            Zmin = Inf;
            for index = 1:height(X)
                point1 = X(index);
                point2 = Y(index);
                point3 = Z(index);
                
                if point1 < Xmin
                    Xmin = point1;
                end
                if point2 < Ymin
                    Ymin = point2;
                end
                if point3 < Zmin
                    Zmin = point3;
                end
            end
            
            if Xmin < 0
                X1(:,1) = X(:,1) + abs(Xmin);
            elseif Xmin > 0
                X1(:,1) = X(:,1) - abs(Xmin);
            else
                X1 = X;
            end
            if Ymin < 0
                Y1(:,1) = Y(:,1) + abs(Ymin);
            elseif Ymin > 0
                Y1(:,1) = Y(:,1) - abs(Ymin);
            else
                Y1 = Y;
            end
            if Zmin < 0
                Z1(:,1) = Z(:,1) + abs(Zmin);
            elseif Zmin > 0
                Z1(:,1) = Z(:,1) - abs(Zmin);
            else
                Z1 = Z;
            end
            
            for i = 1:n_elements
                n1 = ncon(i,1);
                n2 = ncon(i,2);
                n3 = ncon(i,3);
                x1 = X1(n1);
                x2 = X1(n2);
                x3 = X1(n3);
                y1 = Y1(n1);
                y2 = Y1(n2);
                y3 = Y1(n3);
                z1 = Z1(n1);
                z2 = Z1(n2);
                z3 = Z1(n3);
                P =[x1;x2;x3];
                Q = [y1;y2;y3];
                R = [z1;z2;z3];
                hold(app.UIAxes, 'on');
                fill3(app.UIAxes, P,Q,R,'g')  
            end

            app.Xmin = min(X1);
            app.Xmax = max(X1);
            app.Ymin = min(Y1);
            app.Ymax = max(Y1);           
            app.Zmin = min(Z1);
            app.Zmax = max(Z1);
            xlim(app.UIAxes, [app.Xmin app.Xmax]);
            ylim(app.UIAxes, [app.Ymin app.Ymax]);
            zlim(app.UIAxes, [app.Zmin app.Zmax]);
            view(app.UIAxes,[-135 35]);
        end
        
        function viewFFS(app) % Prints Freeform surface
            cla(app.UIAxes); 
            app.model2;
            h = height(app.model2);
            w = width(app.model2);
            l = app.numberLayers +1;
            for layer = 1 : (app.numberLayers + 1)
                Px = app.model2(1:h/3, (layer * w/(l))-w/((l))+1:(layer * w/(l)));
                Py = app.model2((h/3)+1:(h/3)*2, (layer * w/((l))-w/(l))+1:(layer * w/(l)));
                Pz = app.model2((((h/3)*2)+1):h, (layer * w/((l))-w/(l))+1:(layer * w/(l)));
                
                surf(app.UIAxes, Px, Py, Pz);
                hold (app.UIAxes, 'on');
            end
            app.TextArea.Value = "";
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Freeform Surface")]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("STL file: %s" ,(app.file))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Number X points: %d" ,(app.numberX))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Number Y points: %d" ,(app.numberY))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Resolution: %d" ,(app.resolution))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Number of layers: %d" ,(app.numberLayers))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Layer thickness: %d" ,(app.layerThickness))]; 
                             
        end

        function viewCurvedLayer(app) % Prints curved layer
            cla(app.UIAxes);
            app.model3;
            h = height(app.model3);
            w = width(app.model3);
            l = app.numberLayers +1;
            for layer = 1 : (app.numberLayers + 1)
                Px = app.model3(1:h/3, (layer * w/(l))-w/((l))+1:(layer * w/(l)));
                Py = app.model3((h/3)+1:(h/3)*2, (layer * w/((l))-w/(l))+1:(layer * w/(l)));
                Pz = app.model3((((h/3)*2)+1):h, (layer * w/((l))-w/(l))+1:(layer * w/(l)));
                
                plot3(app.UIAxes, Px, Py, Pz);
                hold (app.UIAxes, 'on');
                for n=1:width(Px)
                    if mod(n,2)  == 1       %if Column is odd
                        for i=1:height(Px)%Increment Rows
                            Pstart = [Px(i,n) Py(i,n) Pz(i,n)];
                            if i==height(Px)
                                Pmax = [Px(i,n) Py(i,n) Pz(i,n)];
                            end
                            if i==1
                                Pmin = [Px(i,n) Py(i,n) Pz(i,n)];
                            end
                        end
                    else
                        for q=height(Px):-1:1  %Else decrement Rows
                            Pstart = [Px(q,n) Py(q,n) Pz(q,n)];
                            if q==1
                                Pmin2 = [Px(q,n) Py(q,n) Pz(q,n)];
                            end
                            if q==height(Px)
                                Pmax2 = [Px(q,n) Py(q,n) Pz(q,n)];
                            end
                        end
                    end
                    if n >= 2
                        if mod(n,2)  == 0       %if Column is even
                            %plot3([Pmax(1) Pmax2(1)], [Pmax(2) Pmax2(2)], [Pmax(3) Pmax2(3)]);
                            plot3(app.UIAxes, [Pmax(1) Pmax2(1)], [Pmax(2) Pmax2(2)], [Pmax(3) Pmax2(3)]);
                            hold (app.UIAxes, 'on');
                        else
                            plot3(app.UIAxes,[Pmin(1) Pmin2(1)], [Pmin(2) Pmin2(2)], [Pmin(3) Pmin2(3)]);
                            hold (app.UIAxes, 'on');
                        end
                    end
                end
            end
            app.TextArea.Value = "";
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Curve Layers")]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("STL file: %s" ,(app.file))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Number X points: %d" ,(app.numberX2))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Number Y points: %d" ,(app.numberY2))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Number of paths: %d" ,(app.numberPaths))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Number of points per path: %d" ,(app.numberPoints))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Number of layers: %d" ,(app.numberLayers))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Layer thickness: %d" ,(app.layerThickness))]; 

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t = readtable('AdaptiveParameters.xlsx'); % Starting table
            title(app.UIAxes, '');
            app.UITable.Data = t;
            app.UITable.ColumnEditable = true;
        end

        % Value changed function: IncrementsEditField
        function IncrementsEditFieldValueChanged(app, event)
            value = app.IncrementsEditField.Value;
            app.increments = value;
        end

        % Button pushed function: CustomFileButton
        function CustomFileButtonPushed(app, event)
            app.file = uigetfile;
        end

        % Value changed function: STLfileDropDown
        function STLfileDropDownValueChanged(app, event)
            value = app.STLfileDropDown.Value;
            app.file = value + ".stl";
        end

        % Selection changed function: ViewTypeButtonGroup
        function ViewTypeButtonGroupSelectionChanged(app, event)
            % Change view depending on button pushed
            selectedButton = app.ViewTypeButtonGroup.SelectedObject;
            cla(app.UIAxes);
            numLayers = length(app.model.slicedLayers);
            for layer = 1: numLayers
                data = app.model.getLayerPoints(layer);
                for i = 1 : length(data)
                    XPU(i) = data(i,1);
                    YPU(i) = data(i,2);
                    ZPU(i) = data(i,3);
                end
                for i = 1 : length(data)
                    XPB(i) = data(i,4);
                    YPB(i) = data(i,5);
                    ZPB(i) = data(i,6);
                end
                C = 0;
                for i = 1 : length(data) -1
                    C = C+1;
                    XPA(C) = data(i,1);
                    YPA(C) = data(i,2);
                    ZPA(C) = data(i,3);
                    C = C+1;
                    XPA(C) = data(i,4);
                    YPA(C) = data(i,5);
                    ZPA(C) = data(i,6);
                    C = C+1;
                    XPA(C) = data(i,4);
                    YPA(C) = data(i,5);
                    ZPA(C) = data(i,6);
                    C = C+1;
                    XPA(C) = data(i,1);
                    YPA(C) = data(i,2);
                    ZPA(C) = data(i,3);
                end
                if (selectedButton == app.SlicingButton)
                    plot3(app.UIAxes, XPU,YPU,ZPU,'-r*', 'LineWidth', 0.2); 
                end
                if (selectedButton == app.StructureButton)
                    fill3(app.UIAxes, XPB,YPB,ZPB, 'y')
                    fill3(app.UIAxes, XPU,YPU,ZPU, 'y')
                end
                hold(app.UIAxes, 'on');
            end
            if(selectedButton == app.ModelButton)
                modelView(app);
            end
        end

        % Button pushed function: SimpleSlicingButton
        function SimpleSlicingButtonPushed(app, event)
            % Simple slicing used to compare from adaptive
            app.SimpleSlicingButton.Enable = false;
            Slicing2("Models/" + app.file, app.increments);
            app.TextArea.Value = "";
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Simple Slicing")]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("STL file: %s" ,(app.file))]; 
            app.SimpleSlicingButton.Enable = true;
        end

        % Button pushed function: AdaptiveSlicingButton
        function AdaptiveSlicingButtonPushed(app, event)
            cla(app.UIAxes)
            app.AdaptiveSlicingButton.Enable = false;
            % Load model from script output
            r = AdaptiveSlicingScript("Models/" + app.file, app.increments, app.thicknessArray, app.angleArray, app.residueArray);
            app.model = r;
            
            % Update text box
            app.TextArea.Value = "";
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Adaptive Slicing...")]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("STL file: %s" ,(app.file))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Increments: %d" , app.increments)]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Number of Layers: %d" , length(app.model.slicedLayers))]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Points per Layer: %d" , length(app.model.slicedLayers(1,1).topLayerPoints))]; 
            
            % Update figure and set number of layers
            modelView(app);
            numLayers = length(app.model.slicedLayers);
            app.Slider.Limits = [1 numLayers];
            app.AdaptiveSlicingButton.Enable = true;
        end

        % Value changed function: STLfileDropDown_2
        function STLfileDropDown_2ValueChanged(app, event)
            value = app.STLfileDropDown_2.Value;
            app.file = value + ".stl";
        end

        % Button pushed function: CustomFileButton_2
        function CustomFileButton_2Pushed(app, event)
            app.file = uigetfile;
        end

        % Value changed function: NoofXEditField
        function NoofXEditFieldValueChanged(app, event)
            value = app.NoofXEditField.Value;
            app.numberX = value;
        end

        % Value changed function: NoofYEditField
        function NoofYEditFieldValueChanged(app, event)
            value = app.NoofYEditField.Value;
            app.numberY = value;
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            value = app.ResolutionEditField.Value;
            app.resolution = value;
        end

        % Value changed function: NoofXEditField_2
        function NoofXEditField_2ValueChanged(app, event)
            value = app.NoofXEditField_2.Value;
            app.numberX2 = value;
        end

        % Value changed function: NoofYEditField_2
        function NoofYEditField_2ValueChanged(app, event)
            value = app.NoofYEditField_2.Value;
            app.numberY2 = value;
        end

        % Value changed function: PointsperpathEditField
        function PointsperpathEditFieldValueChanged(app, event)
            value = app.PointsperpathEditField.Value;
            app.numberPoints = value;
        end

        % Value changed function: NoPathsEditField
        function NoPathsEditFieldValueChanged(app, event)
            value = app.NoPathsEditField.Value;
            app.numberPaths = value;
        end

        % Button pushed function: CreatefreeformButton
        function CreatefreeformButtonPushed(app, event)
            app.LoadModelButton.Enable = false;
            app.CurvedLayerSliceButton.Enable = false;
            app.CreatefreeformButton.Enable = false;
            % Load free form model to GUI
            r = FFS_Script("Models/" + app.file, app.numberX, app.numberY, app.resolution, app.numberLayers, app.layerThickness);
            app.model2 = r;
            viewFFS(app);
            app.LoadModelButton.Enable = true;
            app.CurvedLayerSliceButton.Enable = true;
            app.CreatefreeformButton.Enable = true;
        end

        % Button pushed function: CurvedLayerSliceButton
        function CurvedLayerSliceButtonPushed(app, event)
            app.LoadModelButton.Enable = false;
            app.CurvedLayerSliceButton.Enable = false;
            app.CreatefreeformButton.Enable = false;
            % Load curved layer slice model to GUI
            r = CutterPath_Script("Models/" + app.file, app.numberX2, app.numberY2, app.numberPoints, app.numberPaths, app.numberLayers, app.layerThickness);
            app.model3 = r;
            viewCurvedLayer(app);
            app.LoadModelButton.Enable = true;
            app.CurvedLayerSliceButton.Enable = true;
            app.CreatefreeformButton.Enable = true;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            value = round(value);
            selectedButton = app.ViewTypeButtonGroup.SelectedObject;
            cla(app.UIAxes);
            
            data = app.model.getLayerPoints(value);
            for i = 1 : length(data)
                XPU(i) = data(i,1);
                YPU(i) = data(i,2);
                ZPU(i) = data(i,3);
            end
            for i = 1 : length(data)
                XPB(i) = data(i,4);
                YPB(i) = data(i,5);
                ZPB(i) = data(i,6);
            end
            C = 0;
            for i = 1 : length(data) -1
                C = C+1;
                XPA(C) = data(i,1);
                YPA(C) = data(i,2);
                ZPA(C) = data(i,3);
                C = C+1;
                XPA(C) = data(i,4);
                YPA(C) = data(i,5);
                ZPA(C) = data(i,6);
                C = C+1;
                XPA(C) = data(i,4);
                YPA(C) = data(i,5);
                ZPA(C) = data(i,6);
                C = C+1;
                XPA(C) = data(i,1);
                YPA(C) = data(i,2);
                ZPA(C) = data(i,3);
            end
            xlim(app.UIAxes, [app.Xmin app.Xmax]);
            ylim(app.UIAxes, [app.Ymin app.Ymax]);
            zlim(app.UIAxes, [app.Zmin app.Zmax]);
            if (selectedButton == app.SlicingButton)
                plot3(app.UIAxes, XPU,YPU,ZPU,'-r*', 'LineWidth', 0.2); 
            end
            if (selectedButton == app.StructureButton)
                fill3(app.UIAxes, XPB,YPB,ZPB, 'y')
                fill3(app.UIAxes, XPU,YPU,ZPU, 'y')
            end
            hold(app.UIAxes, 'on');

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Update thickness and angle arrays
            indices = event.Indices;
            newData = event.NewData;
            if (indices(2) == 5)
                app.thicknessArray{indices(1) +1} = newData; 
            end
            if (indices(2) == 2)
                app.angleArray{indices(1) +1} = newData; 
            end
            app.thicknessArray{1} = 0;
            if (indices(2) == 4)
                app.residueArray{indices(1) +1} = newData;
            end
            app.residueArray{1} = 0;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear global;
        end

        % Value changed function: LayerThicknessEditField
        function LayerThicknessEditFieldValueChanged(app, event)
            value = app.LayerThicknessEditField.Value;
            app.layerThickness = value;
        end

        % Selection changed function: ViewModeButtonGroup
        function ViewModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewModeButtonGroup.SelectedObject;
            if(selectedButton == app.ModelButton_2)
                app.FreeFormSurfacePanel.Visible = false;
                app.CurvedLayerSlicingPanel.Visible = false;
                app.LayerSettingsPanel.Visible = false;
                modelView(app)
            end
            if(selectedButton == app.OneLayerButton)
                app.FreeFormSurfacePanel.Visible = true;
                app.CurvedLayerSlicingPanel.Visible = true;
                app.LayerSettingsPanel.Visible = false;
                app.numberLayers = 1;
            end
            if(selectedButton == app.MultiLayerButton)
                app.FreeFormSurfacePanel.Visible = true;
                app.CurvedLayerSlicingPanel.Visible = true;
                app.LayerSettingsPanel.Visible = true;
                NumberofLayersSliderValueChanged(app, event)
            end
        end

        % Value changed function: NumberofLayersSlider
        function NumberofLayersSliderValueChanged(app, event)
            value = app.NumberofLayersSlider.Value;
%             if (value>5) % limit to avoid large computational times for testing
%                 value = 5;
%             end
            app.numberLayers = value;
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            app.LoadModelButton.Enable = false;
            app.CurvedLayerSliceButton.Enable = false;
            app.CreatefreeformButton.Enable = false;
            modelView(app)
            app.LoadModelButton.Enable = true;
            app.CurvedLayerSliceButton.Enable = true;
            app.CreatefreeformButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.TextArea = uitextarea(app.PlotPanel);
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AdaptiveSlicingTab = uitab(app.TabGroup);
            app.AdaptiveSlicingTab.Title = 'Adaptive Slicing';
            app.SettingsPanel = uipanel(app.AdaptiveSlicingTab);
            app.SettingsPanel.Title = 'Settings';
            app.STLfileDropDownLabel = uilabel(app.SettingsPanel);
            app.STLfileDropDownLabel.Text = {'STL file'; ''};
            app.STLfileDropDown = uidropdown(app.SettingsPanel);
            app.STLfileDropDown.Items = {'Cone', 'Pyramid', 'Cube', 'AngleChange', 'ProtrudingPart', 'House'};
            app.STLfileDropDown.ValueChangedFcn = createCallbackFcn(app, @STLfileDropDownValueChanged, true);
            app.STLfileDropDown.Value = 'Cone';
            app.IncrementsEditFieldLabel = uilabel(app.SettingsPanel);
            app.IncrementsEditFieldLabel.Text = 'Increments';
            app.IncrementsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.IncrementsEditField.ValueChangedFcn = createCallbackFcn(app, @IncrementsEditFieldValueChanged, true);
            app.IncrementsEditField.Value = 1;
            app.CustomFileButton = uibutton(app.SettingsPanel, 'push');
            app.CustomFileButton.ButtonPushedFcn = createCallbackFcn(app, @CustomFileButtonPushed, true);
            app.CustomFileButton.Text = 'Custom File';
            app.AdaptiveSlicingPanel = uipanel(app.AdaptiveSlicingTab);
            app.AdaptiveSlicingPanel.Title = 'Adaptive Slicing';
            app.UITable = uitable(app.AdaptiveSlicingTab);
            app.UITable.ColumnName = {'Min Angle'; 'Max Angle'; 'Min Residue'; 'Max Residue'; 'Thickness'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SliceTypePanel = uipanel(app.AdaptiveSlicingTab);
            app.SliceTypePanel.Title = 'Slice Type';
            app.SimpleSlicingButton = uibutton(app.SliceTypePanel, 'push');
            app.SimpleSlicingButton.ButtonPushedFcn = createCallbackFcn(app, @SimpleSlicingButtonPushed, true);
            app.SimpleSlicingButton.Text = 'Simple Slicing';
            app.AdaptiveSlicingButton = uibutton(app.SliceTypePanel, 'push');
            app.AdaptiveSlicingButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveSlicingButtonPushed, true);
            app.AdaptiveSlicingButton.Text = 'Adaptive Slicing';
            app.ViewTypeButtonGroup = uibuttongroup(app.AdaptiveSlicingTab);
            app.ViewTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewTypeButtonGroupSelectionChanged, true);
            app.ViewTypeButtonGroup.Title = 'View Type';
            app.ModelButton = uiradiobutton(app.ViewTypeButtonGroup);
            app.ModelButton.Text = 'Model';
            app.ModelButton.Value = true;
            app.SlicingButton = uiradiobutton(app.ViewTypeButtonGroup);
            app.SlicingButton.Text = 'Slicing';
            app.StructureButton = uiradiobutton(app.ViewTypeButtonGroup);
            app.StructureButton.Text = 'Structure';
            app.LayerViewerPanel = uipanel(app.AdaptiveSlicingTab);
            app.LayerViewerPanel.Title = 'Layer Viewer';
            app.SliderLabel = uilabel(app.LayerViewerPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LayerViewerPanel);
            app.Slider.Limits = [1 11];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.CurvedLayerSlicingTab = uitab(app.TabGroup);
            app.CurvedLayerSlicingTab.Title = 'Curved Layer Slicing';
            app.SettingsPanel_2 = uipanel(app.CurvedLayerSlicingTab);
            app.SettingsPanel_2.Title = 'Settings';
            app.STLfileDropDown_2Label = uilabel(app.SettingsPanel_2);
            app.STLfileDropDown_2Label.Text = {'STL file'; ''};
            app.STLfileDropDown_2 = uidropdown(app.SettingsPanel_2);
            app.STLfileDropDown_2.Items = {'Cone', 'Pyramid'};
            app.STLfileDropDown_2.ValueChangedFcn = createCallbackFcn(app, @STLfileDropDown_2ValueChanged, true);
            app.STLfileDropDown_2.Value = 'Cone';
            app.CustomFileButton_2 = uibutton(app.SettingsPanel_2, 'push');
            app.CustomFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @CustomFileButton_2Pushed, true);
            app.CustomFileButton_2.Text = 'Custom File';
            app.LoadModelButton = uibutton(app.SettingsPanel_2, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.FreeFormSurfacePanel = uipanel(app.CurvedLayerSlicingTab);
            app.FreeFormSurfacePanel.Title = 'Free Form Surface';
            app.FreeFormSurfacePanel.Visible = 'off';
            app.NoofXEditFieldLabel = uilabel(app.FreeFormSurfacePanel);
            app.NoofXEditFieldLabel.Text = 'No. of X';
            app.NoofXEditField = uieditfield(app.FreeFormSurfacePanel, 'numeric');
            app.NoofXEditField.ValueChangedFcn = createCallbackFcn(app, @NoofXEditFieldValueChanged, true);
            app.NoofXEditField.Value = 10;
            app.CreatefreeformButton = uibutton(app.FreeFormSurfacePanel, 'push');
            app.CreatefreeformButton.ButtonPushedFcn = createCallbackFcn(app, @CreatefreeformButtonPushed, true);
            app.CreatefreeformButton.Text = 'Create free form';
            app.NoofYEditFieldLabel = uilabel(app.FreeFormSurfacePanel);
            app.NoofYEditFieldLabel.Text = 'No. of Y';
            app.NoofYEditField = uieditfield(app.FreeFormSurfacePanel, 'numeric');
            app.NoofYEditField.ValueChangedFcn = createCallbackFcn(app, @NoofYEditFieldValueChanged, true);
            app.NoofYEditField.Value = 10;
            app.ResolutionEditFieldLabel = uilabel(app.FreeFormSurfacePanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.FreeFormSurfacePanel, 'numeric');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditField.Value = 30;
            app.LayerThicknessEditFieldLabel = uilabel(app.FreeFormSurfacePanel);
            app.LayerThicknessEditFieldLabel.Text = 'Layer Thickness';
            app.LayerThicknessEditField = uieditfield(app.FreeFormSurfacePanel, 'numeric');
            app.LayerThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @LayerThicknessEditFieldValueChanged, true);
            app.LayerThicknessEditField.Value = 0.1;
            app.CurvedLayerSlicingPanel = uipanel(app.CurvedLayerSlicingTab);
            app.CurvedLayerSlicingPanel.Title = 'Curved Layer Slicing';
            app.CurvedLayerSlicingPanel.Visible = 'off';
            app.NoofXEditField_2Label = uilabel(app.CurvedLayerSlicingPanel);
            app.NoofXEditField_2Label.Text = 'No. of X';
            app.NoofXEditField_2 = uieditfield(app.CurvedLayerSlicingPanel, 'numeric');
            app.NoofXEditField_2.ValueChangedFcn = createCallbackFcn(app, @NoofXEditField_2ValueChanged, true);
            app.NoofXEditField_2.Value = 5;
            app.CurvedLayerSliceButton = uibutton(app.CurvedLayerSlicingPanel, 'push');
            app.CurvedLayerSliceButton.ButtonPushedFcn = createCallbackFcn(app, @CurvedLayerSliceButtonPushed, true);
            app.CurvedLayerSliceButton.Text = 'Curved Layer Slice';
            app.NoofYEditField_2Label = uilabel(app.CurvedLayerSlicingPanel);
            app.NoofYEditField_2Label.Text = 'No. of Y';
            app.NoofYEditField_2 = uieditfield(app.CurvedLayerSlicingPanel, 'numeric');
            app.NoofYEditField_2.ValueChangedFcn = createCallbackFcn(app, @NoofYEditField_2ValueChanged, true);
            app.NoofYEditField_2.Value = 5;
            app.PointsperpathEditFieldLabel = uilabel(app.CurvedLayerSlicingPanel);
            app.PointsperpathEditFieldLabel.Text = 'Points per path';
            app.PointsperpathEditField = uieditfield(app.CurvedLayerSlicingPanel, 'numeric');
            app.PointsperpathEditField.ValueChangedFcn = createCallbackFcn(app, @PointsperpathEditFieldValueChanged, true);
            app.PointsperpathEditField.Value = 30;
            app.NoPathsEditFieldLabel = uilabel(app.CurvedLayerSlicingPanel);
            app.NoPathsEditFieldLabel.Text = 'No. Paths';
            app.NoPathsEditField = uieditfield(app.CurvedLayerSlicingPanel, 'numeric');
            app.NoPathsEditField.ValueChangedFcn = createCallbackFcn(app, @NoPathsEditFieldValueChanged, true);
            app.NoPathsEditField.Value = 10;
            app.ViewModeButtonGroup = uibuttongroup(app.CurvedLayerSlicingTab);
            app.ViewModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewModeButtonGroupSelectionChanged, true);
            app.ViewModeButtonGroup.Title = 'View Mode';
            app.ModelButton_2 = uiradiobutton(app.ViewModeButtonGroup);
            app.ModelButton_2.Text = 'Model';
            app.ModelButton_2.Value = true;
            app.OneLayerButton = uiradiobutton(app.ViewModeButtonGroup);
            app.OneLayerButton.Text = 'One Layer';
            app.MultiLayerButton = uiradiobutton(app.ViewModeButtonGroup);
            app.MultiLayerButton.Text = 'Multi Layer';
            app.LayerSettingsPanel = uipanel(app.CurvedLayerSlicingTab);
            app.LayerSettingsPanel.Title = 'Layer Settings';
            app.LayerSettingsPanel.Visible = 'off';
            app.NumberofLayersLabel = uilabel(app.LayerSettingsPanel);
            app.NumberofLayersLabel.Text = {'Number '; 'of Layers'};
            app.NumberofLayersSlider = uislider(app.LayerSettingsPanel);
            app.NumberofLayersSlider.Limits = [1 30];
            app.NumberofLayersSlider.MajorTicks = [0 5 10 15 20 25 30];
            app.NumberofLayersSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofLayersSliderValueChanged, true);
            app.NumberofLayersSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.NumberofLayersSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end